classdef appur3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        PLAY_2              matlab.ui.control.StateButton
        Panel               matlab.ui.container.Panel
        zEditField          matlab.ui.control.NumericEditField
        zEditFieldLabel     matlab.ui.control.Label
        yEditField          matlab.ui.control.NumericEditField
        yEditFieldLabel     matlab.ui.control.Label
        xEditField          matlab.ui.control.NumericEditField
        xEditFieldLabel     matlab.ui.control.Label
        Meca                matlab.ui.container.Panel
        Joint_1Slider       matlab.ui.control.Slider
        Joint_1SliderLabel  matlab.ui.control.Label
        Joint_6Slider       matlab.ui.control.Slider
        Joint_6SliderLabel  matlab.ui.control.Label
        Joint_5Slider       matlab.ui.control.Slider
        Joint_5SliderLabel  matlab.ui.control.Label
        Joint_4Slider       matlab.ui.control.Slider
        Joint_4SliderLabel  matlab.ui.control.Label
        Joint_3Slider       matlab.ui.control.Slider
        Joint_3SliderLabel  matlab.ui.control.Label
        Joint_2Slider       matlab.ui.control.Slider
        Joint_2SliderLabel  matlab.ui.control.Label
        ESTOPButton         matlab.ui.control.StateButton
        PLAY                matlab.ui.control.StateButton
        PAUSE               matlab.ui.control.StateButton
        STOP                matlab.ui.control.StateButton
        UR3                 matlab.ui.container.Panel
        Joint6Slider        matlab.ui.control.Slider
        Joint6SliderLabel   matlab.ui.control.Label
        Joint5Slider        matlab.ui.control.Slider
        Joint5SliderLabel   matlab.ui.control.Label
        Joint4Slider        matlab.ui.control.Slider
        Joint4SliderLabel   matlab.ui.control.Label
        Joint3Slider        matlab.ui.control.Slider
        Joint3SliderLabel   matlab.ui.control.Label
        Joint2Slider        matlab.ui.control.Slider
        Joint2SliderLabel   matlab.ui.control.Label
        Joint1Slider        matlab.ui.control.Slider
        Joint1SliderLabel   matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       r;
       r2;
       q = zeros(1,6);
       q2 = zeros(1,6);
       deg = pi/180;
       workspace = [-2 2 -2 2 -0.61 2]; 

    %way point of UR3
    qq0 = [0 0 0 0 0 0];
    qq = [0 -60 0 0 0 0]*pi/180;
    qm = [150 -60 120 -60 60 90]*pi/180;
    qq1 = [155.7657 (314.3879-360) 98.7706 (306.8437-360) (360-294.2363) 89.9994]*pi/180;
    qq2 = [155.7498  (307.2031-360)   97.7479  (315.0528-360) (360-294.2514)   90.0021]*pi/180;
    qq3 = [-40 -30 0 0 0 120]*pi/180;
    %way point of MECA500
    qt0 = [0,0,0,0,0,0];
    qr = [-90 -20 -60 0 -78 0]*pi/180;
    qt1 = [-89.9921 -36.0242 -37.4012 0.0144 -73.4214 -0.0072]*pi/180;
    qt2 = [90 -70 30 0 -40 0]*pi/180;
    qts1 = [0,0,0,0,0,45]*pi/180;
    qts2 = [0,0,0,0,0,-45]*pi/180;

    end
    methods (Access = private)
        function edit(app)
            x = app.xEditField.Value;
            y = app.yEditField.Value;
            z = app.zEditField.Value;
            tf = transl(x,y,z);
            app.q = app.r.my3.ikcon(tf,app.q);
            app.r.my3.animate(app.q);
            app.q2 = app.r.my3.ikcon(tf,app.q);
            app.r2.model.animate(app.q2);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           axes(app.UIAxes);
           cla(app.UIAxes)
           ax = gca;
           app.r2 = myUR3;
           hold on
           app.r = Meca500;
           enviGUI();
%            qz = [pi/2 0 0 0 pi/4 pi];
%            app.r2.model.animate(qz);
           drawnow();

           for i = size(ax.Children,1):-1:1
               if strcmp(ax.Children(i).Type,'surface')
                   delete(ax.Children(i));
               end
           end

           while 0 < length(ax.Children)
               set(ax.Children(1), 'Parent', app.UIAxes);
           end

           delete(get(gca,'Parent'));
           axis equal;
           app.UIAxes.DataAspectRatio = [1 1 1];
           app.UIAxes.View = [-135, 25];

           app.PLAY.Enable = true;
           app.ESTOPButton.Enable = "off";

        end

        % Value changed function: Joint_1Slider
        function Joint_1SliderValueChanged(app, event)
           handle = [];
            for childIndex = 1:length(app.UIAxes.Children)
                if strcmp(app.UIAxes.Children(childIndex).Tag,app.r.my3.name)
                    handle = app.UIAxes.Children(childIndex);
                end
            end
                
            app.q(1) = event.Value*app.deg;
            animate(app.r.my3,app.q, handle);
     
        end

        % Value changed function: Joint_2Slider
        function Joint_2SliderValueChanged(app, event)
              handle = [];
            for childIndex = 1:length(app.UIAxes.Children)
                if strcmp(app.UIAxes.Children(childIndex).Tag,app.r.my3.name)
                    handle = app.UIAxes.Children(childIndex);
                end
            end
                
            app.q(2) = event.Value*app.deg;
            animate(app.r.my3,app.q, handle);
        end

        % Value changed function: Joint_3Slider
        function Joint_3SliderValueChanged(app, event)
              handle = [];
            for childIndex = 1:length(app.UIAxes.Children)
                if strcmp(app.UIAxes.Children(childIndex).Tag,app.r.my3.name)
                    handle = app.UIAxes.Children(childIndex);
                end
            end
                
            app.q(3) = event.Value*app.deg;
            animate(app.r.my3,app.q,handle);
        end

        % Value changed function: Joint_4Slider
        function Joint_4SliderValueChanged(app, event)
            handle = [];
            for childIndex = 1:length(app.UIAxes.Children)
                if strcmp(app.UIAxes.Children(childIndex).Tag,app.r.my3.name)
                    handle = app.UIAxes.Children(childIndex);
                end
            end
                
            app.q(4) = event.Value*app.deg;
            animate(app.r.my3,app.q,handle);
        end

        % Value changed function: Joint_5Slider
        function Joint_5SliderValueChanged(app, event)
            handle = [];
            for childIndex = 1:length(app.UIAxes.Children)
                if strcmp(app.UIAxes.Children(childIndex).Tag,app.r.my3.name)
                    handle = app.UIAxes.Children(childIndex);
                end
            end
                
            app.q(5) = event.Value*app.deg;
            animate(app.r.my3,app.q,handle);
        end

        % Value changed function: Joint_6Slider
        function Joint_6SliderValueChanged(app, event)
            handle = [];
            for childIndex = 1:length(app.UIAxes.Children)
                if strcmp(app.UIAxes.Children(childIndex).Tag,app.r.my3.name)
                    handle = app.UIAxes.Children(childIndex);
                end
            end
                
            app.q(6) = event.Value*app.deg;
            animate(app.r.my3,app.q,handle);
        end

        % Value changed function: zEditField
        function zEditFieldValueChanged(app, event)
            edit(app);
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            edit(app);
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            edit(app);
        end

        % Value changed function: ESTOPButton
        function ESTOPButtonValueChanged(app, event)
            assignin("base","ESTOPButton",false);
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            handle = [];
            for childIndex = 1:length(app.UIAxes.Children)
                if strcmp(app.UIAxes.Children(childIndex).Tag,app.r2.model.name)
                    handle = app.UIAxes.Children(childIndex);
                end
            end
                
            app.q2(1) = event.Value*app.deg;
            animate(app.r2.model,app.q2,handle);
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            handle = [];
            for childIndex = 1:length(app.UIAxes.Children)
                if strcmp(app.UIAxes.Children(childIndex).Tag,app.r2.model.name)
                    handle = app.UIAxes.Children(childIndex);
                end
            end
                
            app.q2(2) = event.Value*app.deg;
            animate(app.r2.model,app.q2,handle);
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            handle = [];
            for childIndex = 1:length(app.UIAxes.Children)
                if strcmp(app.UIAxes.Children(childIndex).Tag,app.r2.model.name)
                    handle = app.UIAxes.Children(childIndex);
                end
            end
                
            app.q2(3) = event.Value*app.deg;
            animate(app.r2.model,app.q2,handle);
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            handle = [];
            for childIndex = 1:length(app.UIAxes.Children)
                if strcmp(app.UIAxes.Children(childIndex).Tag,app.r2.model.name)
                    handle = app.UIAxes.Children(childIndex);
                end
            end
                
            app.q2(4) = event.Value*app.deg;
            animate(app.r2.model,app.q2,handle);
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            handle = [];
            for childIndex = 1:length(app.UIAxes.Children)
                if strcmp(app.UIAxes.Children(childIndex).Tag,app.r2.model.name)
                    handle = app.UIAxes.Children(childIndex);
                end
            end
                
            app.q2(5) = event.Value*app.deg;
            animate(app.r2.model,app.q2,handle);
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            handle = [];
            for childIndex = 1:length(app.UIAxes.Children)
                if strcmp(app.UIAxes.Children(childIndex).Tag,app.r2.model.name)
                    handle = app.UIAxes.Children(childIndex);
                end
            end
                
            app.q2(6) = event.Value*app.deg;
            animate(app.r2.model,app.q2,handle);
        end

        % Value changed function: PLAY
        function PLAYValueChanged(app, event)
            % UR3 trajectory
            steps = 100;
            qii = jtraj(app.qq0,app.qq,steps);
            qi0 = jtraj(app.qq,app.qm,steps);
            qi1 = jtraj(app.qm,app.qq1,steps);
            qi2 = jtraj(app.qq1,app.qq2,steps);
            qi3  = jtraj(app.qq2,app.qm,steps);
            qi4  = jtraj(app.qm,app.qq3,steps);   

            for robotStepIndex = 1:size(qii,1)
%                 if(app.ESTOPButton.Enable == "off" )
%                         app.ESTOPButton.Enable = "on";
%                          while(app.ESTOPButton.Enable == "on" )
%                             if(app.ESTOPButton == true)
%                                 error('Emergency Stop');
%                             end                         
%                          end    
%                 end

                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.r2.model.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end
                animate(app.r2.model,qii(robotStepIndex,:),handle);
                drawnow
            end

            for robotStepIndex = 1:size(qi0,1)
                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.r2.model.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end
                animate(app.r2.model,qi0(robotStepIndex,:),handle);
                drawnow
            end

            for robotStepIndex = 1:size(qi1,1)
                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.r2.model.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end
                animate(app.r2.model,qi1(robotStepIndex,:),handle);
                drawnow
            end

             for robotStepIndex = 1:size(qi2,1)
                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.r2.model.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end
                animate(app.r2.model,qi2(robotStepIndex,:),handle);
                drawnow
             end

              for robotStepIndex = 1:size(qi3,1)
                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.r2.model.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end
                animate(app.r2.model,qi3(robotStepIndex,:),handle);
                drawnow
              end

              for robotStepIndex = 1:size(qi4,1)
                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.r2.model.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end
                animate(app.r2.model,qi4(robotStepIndex,:),handle);
                drawnow
            end
        end

        % Value changed function: PLAY_2
        function PLAY_2ValueChanged(app, event)
            % Meca 500 trajectory
            steps = 100;
            qj0 = jtraj(app.qt0,app.qr,steps);
            qj1 = jtraj(app.qr,app.qt1,steps);
            qj2 = jtraj(app.qt1,app.qt2,steps);
%             qj3 = jtraj(app.qt2,app.qt0,steps);
%             qj4 = jtraj(app.qt0,app.qts1,steps);
%             qj5 = jtraj(app.qts1,app.qts2,steps);
%             qj6 = jtraj(app.qts2,app.qts1,steps);

            for robotStepIndex = 1:size(qj0,1)
                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.r.my3.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end
                animate(app.r.my3,qj0(robotStepIndex,:),handle);
                drawnow
            end
            for robotStepIndex = 1:size(qj1,1)
                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.r.my3.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end
                animate(app.r.my3,qj1(robotStepIndex,:),handle);
                drawnow
            end
            for robotStepIndex = 1:size(qj2,1)
                handle = [];
                for childIndex = 1:length(app.UIAxes.Children)
                    if strcmp(app.UIAxes.Children(childIndex).Tag,app.r.my3.name)
                        handle = app.UIAxes.Children(childIndex);
                    end
                end
                animate(app.r.my3,qj2(robotStepIndex,:),handle);
                drawnow
            end
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'white');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'PLOT')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UR3 = uipanel(app.UIFigure);
            app.UR3.Title = 'UR3';
            app.Joint1SliderLabel = uilabel(app.UR3);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1Slider = uislider(app.UR3);
            app.Joint1Slider.Limits = [-360 360];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.UR3);
            app.Joint2SliderLabel.Text = 'Joint2';
            app.Joint2Slider = uislider(app.UR3);
            app.Joint2Slider.Limits = [-360 360];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint3SliderLabel = uilabel(app.UR3);
            app.Joint3SliderLabel.Text = 'Joint3';
            app.Joint3Slider = uislider(app.UR3);
            app.Joint3Slider.Limits = [-360 360];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint4SliderLabel = uilabel(app.UR3);
            app.Joint4SliderLabel.Text = 'Joint4';
            app.Joint4Slider = uislider(app.UR3);
            app.Joint4Slider.Limits = [-360 360];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.UR3);
            app.Joint5SliderLabel.Text = 'Joint5';
            app.Joint5Slider = uislider(app.UR3);
            app.Joint5Slider.Limits = [-360 360];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint6SliderLabel = uilabel(app.UR3);
            app.Joint6SliderLabel.Text = 'Joint6';
            app.Joint6Slider = uislider(app.UR3);
            app.Joint6Slider.Limits = [-360 360];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.STOP = uibutton(app.UIFigure, 'state');
            app.STOP.Text = '⏹';
            app.PAUSE = uibutton(app.UIFigure, 'state');
            app.PAUSE.Text = '⏯';
            app.PLAY = uibutton(app.UIFigure, 'state');
            app.PLAY.ValueChangedFcn = createCallbackFcn(app, @PLAYValueChanged, true);
            app.PLAY.Text = '▶️';
            app.ESTOPButton = uibutton(app.UIFigure, 'state');
            app.ESTOPButton.ValueChangedFcn = createCallbackFcn(app, @ESTOPButtonValueChanged, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.Meca = uipanel(app.UIFigure);
            app.Meca.Title = 'MECA 500';
            app.Joint_2SliderLabel = uilabel(app.Meca);
            app.Joint_2SliderLabel.Text = 'Joint_2';
            app.Joint_2Slider = uislider(app.Meca);
            app.Joint_2Slider.Limits = [-70 90];
            app.Joint_2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint_2SliderValueChanged, true);
            app.Joint_3SliderLabel = uilabel(app.Meca);
            app.Joint_3SliderLabel.Text = 'Joint_3';
            app.Joint_3Slider = uislider(app.Meca);
            app.Joint_3Slider.Limits = [-70 135];
            app.Joint_3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint_3SliderValueChanged, true);
            app.Joint_4SliderLabel = uilabel(app.Meca);
            app.Joint_4SliderLabel.Text = 'Joint_4';
            app.Joint_4Slider = uislider(app.Meca);
            app.Joint_4Slider.Limits = [-170 170];
            app.Joint_4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint_4SliderValueChanged, true);
            app.Joint_5SliderLabel = uilabel(app.Meca);
            app.Joint_5SliderLabel.Text = 'Joint_5';
            app.Joint_5Slider = uislider(app.Meca);
            app.Joint_5Slider.Limits = [-115 115];
            app.Joint_5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint_5SliderValueChanged, true);
            app.Joint_6SliderLabel = uilabel(app.Meca);
            app.Joint_6SliderLabel.Text = 'Joint_6';
            app.Joint_6Slider = uislider(app.Meca);
            app.Joint_6Slider.Limits = [-115 115];
            app.Joint_6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint_6SliderValueChanged, true);
            app.Joint_1SliderLabel = uilabel(app.Meca);
            app.Joint_1SliderLabel.Text = 'Joint_1';
            app.Joint_1Slider = uislider(app.Meca);
            app.Joint_1Slider.Limits = [-175 175];
            app.Joint_1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint_1SliderValueChanged, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.xEditFieldLabel = uilabel(app.Panel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.Panel, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField.Value = 0.2604;
            app.yEditFieldLabel = uilabel(app.Panel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.Panel, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.zEditFieldLabel = uilabel(app.Panel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.Panel, 'numeric');
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @zEditFieldValueChanged, true);
            app.zEditField.Value = 0.1235;
            app.PLAY_2 = uibutton(app.UIFigure, 'state');
            app.PLAY_2.ValueChangedFcn = createCallbackFcn(app, @PLAY_2ValueChanged, true);
            app.PLAY_2.Text = '▶️';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appur3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end